classdef kvsettingsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        FavouriteXAxisVariablesPanel  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TextArea                      matlab.ui.control.TextArea
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        kviewAppHandle % handle to the kview
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kviewAppHandle)
            app.kviewAppHandle = kviewAppHandle;
            app.TextArea.Value = kviewAppHandle.Settings.favouriteXAxisList;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            favouriteXAxisList = string(app.TextArea.Value(:).');
            app.kviewAppHandle.Settings.favouriteXAxisList = favouriteXAxisList;
            setpref('kview','favouriteXAxisList',favouriteXAxisList);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FavouriteXAxisVariablesPanel = uipanel(app.GridLayout);
            app.FavouriteXAxisVariablesPanel.Tooltip = {'The variables listed here can be selected by righ clicling on the xaxis "set default" button.'};
            app.FavouriteXAxisVariablesPanel.Title = 'Favourite X Axis Variables';
            app.GridLayout2 = uigridlayout(app.FavouriteXAxisVariablesPanel);
            app.TextArea = uitextarea(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kvsettingsGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end